classdef TLineApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        CharcteristicImpedanceohmsEditFieldLabel  matlab.ui.control.Label
        CharcteristicImpedanceohmsEditField  matlab.ui.control.NumericEditField
        TransmissionLineLengthmEditFieldLabel  matlab.ui.control.Label
        TransmissionLineLengthmEditField  matlab.ui.control.NumericEditField
        PhaseVelocityx108msEditFieldLabel  matlab.ui.control.Label
        PhaseVelocityx108msEditField   matlab.ui.control.NumericEditField
        SourceVoltageVEditFieldLabel   matlab.ui.control.Label
        SourceVoltageVEditField        matlab.ui.control.NumericEditField
        SourceResistanceohmsEditFieldLabel  matlab.ui.control.Label
        SourceResistanceohmsEditField  matlab.ui.control.NumericEditField
        SourceReactanceohmsEditFieldLabel  matlab.ui.control.Label
        SourceReactanceohmsEditField   matlab.ui.control.NumericEditField
        LoadResistanceohmsEditFieldLabel  matlab.ui.control.Label
        LoadResistanceohmsEditField    matlab.ui.control.NumericEditField
        LoadReactanceohmsEditFieldLabel  matlab.ui.control.Label
        LoadReactanceohmsEditField     matlab.ui.control.NumericEditField
        ControlPanel                   matlab.ui.container.Panel
        SliderLabel                    matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        SweepVarSwitchLabel            matlab.ui.control.Label
        SweepVarSwitch                 matlab.ui.control.Switch
        OutputPanel                    matlab.ui.container.Panel
        InductancenHEditFieldLabel     matlab.ui.control.Label
        InductancenHEditField          matlab.ui.control.NumericEditField
        CapacitancepFEditFieldLabel    matlab.ui.control.Label
        CapacitancepFEditField         matlab.ui.control.NumericEditField
        TimeConstantsEditFieldLabel    matlab.ui.control.Label
        TimeConstantsEditField         matlab.ui.control.NumericEditField
        LoadReflectionCoefficientEditFieldLabel  matlab.ui.control.Label
        LoadReflectionCoefficientEditField  matlab.ui.control.NumericEditField
        SourceReflectionCoefficientEditFieldLabel  matlab.ui.control.Label
        SourceReflectionCoefficientEditField  matlab.ui.control.NumericEditField
        SteadyStateVoltageVEditFieldLabel  matlab.ui.control.Label
        SteadyStateVoltageVEditField   matlab.ui.control.NumericEditField
        SteadyStateCurrentmAEditFieldLabel  matlab.ui.control.Label
        SteadyStateCurrentmAEditField  matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CharacteristicImpedance = 100
        PhaseVelocity = 3e8;
        
        SourceVoltage = 12;
        SourceResistance = 50;
        LoadResistance = 200;
        
        InductancenH = 0;
        CapacitancepF = 0;
        
        LoadReflectionCoefficient = 0;
        SourceReflectionCoefficient = 0;
        
        SteadyStateVoltageV = 0;
        SteadyStateCurrentmA = 0;
    end
    
    methods (Access = private)
        
        function updateCircuitElementValues(app)
            try
                [app.InductancenH, app.CapacitancepF] = getCircuitElementValues(app.CharacteristicImpedance, app.PhaseVelocity);
                app.InductancenHEditField.Value = app.InductancenH;
                app.CapacitancepFEditField.Value = app.CapacitancepF; 
            catch ME
                disp(ME.message);
            end
        end
        
        function updateSteadyStateMeasurements(app)
            try
                app.SteadyStateVoltageV = getSteadyStateVoltage(app.SourceVoltage, app.SourceResistance, app.LoadResistance);
                app.SteadyStateCurrentmA = getSteadyStateCurrent(app.SourceVoltage, app.SourceResistance, app.LoadResistance);
                
                app.SteadyStateVoltageVEditField.Value = app.SteadyStateVoltageV;
                app.SteadyStateCurrentmAEditField.Value = app.SteadyStateCurrentmA;   
            catch ME
                disp(ME.message);
            end
        end
        
        
        function updateLoadReflectionCoefficient(app)
            try
                app.LoadReflectionCoefficient = getReflectionCoefficient(app.CharacteristicImpedance, app.LoadResistance);
                app.LoadReflectionCoefficientEditField.Value = app.LoadReflectionCoefficient;
            catch ME
                disp(ME.message);
            end
        end
        
        function updateSourceReflectionCoefficient(app)
            try
                app.SourceReflectionCoefficient = getReflectionCoefficient(app.CharacteristicImpedance, app.SourceResistance);
                app.SourceReflectionCoefficientEditField.Value = app.SourceReflectionCoefficient;
            catch ME
                disp(ME.message);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updateCircuitElementValues(app);
            updateSteadyStateMeasurements(app);
            updateLoadReflectionCoefficient(app);
            updateSourceReflectionCoefficient(app);
        end

        % Value changed function: 
        % CharcteristicImpedanceohmsEditField
        function CharcteristicImpedanceohmsEditFieldValueChanged(app, event)
            app.CharacteristicImpedance = app.CharcteristicImpedanceohmsEditField.Value;
            updateCircuitElementValues(app);
        end

        % Value changed function: PhaseVelocityx108msEditField
        function PhaseVelocityx108msEditFieldValueChanged(app, event)
            app.PhaseVelocity = app.PhaseVelocityx108msEditField.Value * 10^8;
            updateCircuitElementValues(app);
        end

        % Value changed function: SourceVoltageVEditField
        function SourceVoltageVEditFieldValueChanged(app, event)
            app.SourceVoltage = app.SourceVoltageVEditField.Value;
            updateSteadyStateMeasurements(app);
        end

        % Value changed function: SourceResistanceohmsEditField
        function SourceResistanceohmsEditFieldValueChanged(app, event)
            app.SourceResistance = app.SourceResistanceohmsEditField.Value;
            updateSteadyStateMeasurements(app);
            updateSourceReflectionCoefficient(app);
        end

        % Value changed function: LoadResistanceohmsEditField
        function LoadResistanceohmsEditFieldValueChanged(app, event)
            app.LoadResistance = app.LoadResistanceohmsEditField.Value;
            updateSteadyStateMeasurements(app);
            updateLoadReflectionCoefficient(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.CharcteristicImpedanceohmsEditFieldLabel = uilabel(app.Panel);
            app.CharcteristicImpedanceohmsEditFieldLabel.Text = 'Charcteristic Impedance (ohms)';
            app.CharcteristicImpedanceohmsEditField = uieditfield(app.Panel, 'numeric');
            app.CharcteristicImpedanceohmsEditField.ValueChangedFcn = createCallbackFcn(app, @CharcteristicImpedanceohmsEditFieldValueChanged, true);
            app.CharcteristicImpedanceohmsEditField.Value = 100;
            app.TransmissionLineLengthmEditFieldLabel = uilabel(app.Panel);
            app.TransmissionLineLengthmEditFieldLabel.Text = 'Transmission Line Length (m)';
            app.TransmissionLineLengthmEditField = uieditfield(app.Panel, 'numeric');
            app.PhaseVelocityx108msEditFieldLabel = uilabel(app.Panel);
            app.PhaseVelocityx108msEditFieldLabel.Text = 'Phase Velocity (x 10^8) (m/s)';
            app.PhaseVelocityx108msEditField = uieditfield(app.Panel, 'numeric');
            app.PhaseVelocityx108msEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseVelocityx108msEditFieldValueChanged, true);
            app.PhaseVelocityx108msEditField.Value = 3;
            app.SourceVoltageVEditFieldLabel = uilabel(app.Panel);
            app.SourceVoltageVEditFieldLabel.Text = 'Source Voltage (V)';
            app.SourceVoltageVEditField = uieditfield(app.Panel, 'numeric');
            app.SourceVoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @SourceVoltageVEditFieldValueChanged, true);
            app.SourceVoltageVEditField.Value = 12;
            app.SourceResistanceohmsEditFieldLabel = uilabel(app.Panel);
            app.SourceResistanceohmsEditFieldLabel.Text = 'Source Resistance (ohms)';
            app.SourceResistanceohmsEditField = uieditfield(app.Panel, 'numeric');
            app.SourceResistanceohmsEditField.ValueChangedFcn = createCallbackFcn(app, @SourceResistanceohmsEditFieldValueChanged, true);
            app.SourceResistanceohmsEditField.Value = 50;
            app.SourceReactanceohmsEditFieldLabel = uilabel(app.Panel);
            app.SourceReactanceohmsEditFieldLabel.Text = 'Source Reactance (ohms)';
            app.SourceReactanceohmsEditField = uieditfield(app.Panel, 'numeric');
            app.LoadResistanceohmsEditFieldLabel = uilabel(app.Panel);
            app.LoadResistanceohmsEditFieldLabel.Text = 'Load Resistance (ohms)';
            app.LoadResistanceohmsEditField = uieditfield(app.Panel, 'numeric');
            app.LoadResistanceohmsEditField.ValueChangedFcn = createCallbackFcn(app, @LoadResistanceohmsEditFieldValueChanged, true);
            app.LoadResistanceohmsEditField.Value = 200;
            app.LoadReactanceohmsEditFieldLabel = uilabel(app.Panel);
            app.LoadReactanceohmsEditFieldLabel.Text = 'Load Reactance (ohms)';
            app.LoadReactanceohmsEditField = uieditfield(app.Panel, 'numeric');
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.SliderLabel = uilabel(app.ControlPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.ControlPanel);
            app.SweepVarSwitchLabel = uilabel(app.ControlPanel);
            app.SweepVarSwitchLabel.Text = 'Sweep Var';
            app.SweepVarSwitch = uiswitch(app.ControlPanel, 'slider');
            app.SweepVarSwitch.Items = {'Sweep Time ', 'Sweep Length'};
            app.SweepVarSwitch.Value = 'Sweep Time ';
            app.OutputPanel = uipanel(app.UIFigure);
            app.OutputPanel.Title = 'Output Panel';
            app.InductancenHEditFieldLabel = uilabel(app.OutputPanel);
            app.InductancenHEditFieldLabel.Text = 'Inductance (nH)';
            app.InductancenHEditField = uieditfield(app.OutputPanel, 'numeric');
            app.CapacitancepFEditFieldLabel = uilabel(app.OutputPanel);
            app.CapacitancepFEditFieldLabel.Text = 'Capacitance (pF)';
            app.CapacitancepFEditField = uieditfield(app.OutputPanel, 'numeric');
            app.TimeConstantsEditFieldLabel = uilabel(app.OutputPanel);
            app.TimeConstantsEditFieldLabel.Text = 'Time Constant (s)';
            app.TimeConstantsEditField = uieditfield(app.OutputPanel, 'numeric');
            app.LoadReflectionCoefficientEditFieldLabel = uilabel(app.OutputPanel);
            app.LoadReflectionCoefficientEditFieldLabel.Text = 'Load Reflection Coefficient';
            app.LoadReflectionCoefficientEditField = uieditfield(app.OutputPanel, 'numeric');
            app.SourceReflectionCoefficientEditFieldLabel = uilabel(app.OutputPanel);
            app.SourceReflectionCoefficientEditFieldLabel.Text = 'Source Reflection Coefficient';
            app.SourceReflectionCoefficientEditField = uieditfield(app.OutputPanel, 'numeric');
            app.SteadyStateVoltageVEditFieldLabel = uilabel(app.OutputPanel);
            app.SteadyStateVoltageVEditFieldLabel.Text = 'Steady-State Voltage (V)';
            app.SteadyStateVoltageVEditField = uieditfield(app.OutputPanel, 'numeric');
            app.SteadyStateCurrentmAEditFieldLabel = uilabel(app.OutputPanel);
            app.SteadyStateCurrentmAEditFieldLabel.Text = 'Steady State Current (mA)';
            app.SteadyStateCurrentmAEditField = uieditfield(app.OutputPanel, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3D plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TLineApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end